classdef TRONWARE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TransformationMatrixLabel  matlab.ui.control.Label
        UITable3                   matlab.ui.control.Table
        DHParametersLabel          matlab.ui.control.Label
        UITable2                   matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
        Panel_2                    matlab.ui.container.Panel
        ClearButton                matlab.ui.control.Button
        RecordButton               matlab.ui.control.Button
        Panel_3                    matlab.ui.container.Panel
        OUTPUTListBox              matlab.ui.control.ListBox
        OUTPUTListBoxLabel         matlab.ui.control.Label
        PLOTButton                 matlab.ui.control.Button
        DropDown                   matlab.ui.control.DropDown
        PlotTrajectoryLabel        matlab.ui.control.Label
        EditField_8                matlab.ui.control.NumericEditField
        EditField_7                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        Button_14                  matlab.ui.control.Button
        ZLabel                     matlab.ui.control.Label
        Button_13                  matlab.ui.control.Button
        Button_12                  matlab.ui.control.Button
        YLabel                     matlab.ui.control.Label
        Button_11                  matlab.ui.control.Button
        Button_10                  matlab.ui.control.Button
        XLabel                     matlab.ui.control.Label
        Button_9                   matlab.ui.control.Button
        Switch                     matlab.ui.control.Switch
        GripperLabel               matlab.ui.control.Label
        EditField_5                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        Button_8                   matlab.ui.control.Button
        J4Label                    matlab.ui.control.Label
        Button_7                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        J3Label                    matlab.ui.control.Label
        Button_5                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        J2Label                    matlab.ui.control.Label
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        J1Label                    matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        LoopEditField              matlab.ui.control.NumericEditField
        LoopEditFieldLabel         matlab.ui.control.Label
        PlayButton                 matlab.ui.control.Button
        Button_15                  matlab.ui.control.Button
        LockCheckBox               matlab.ui.control.CheckBox
        EditField_10               matlab.ui.control.NumericEditField
        SpeedLabel                 matlab.ui.control.Label
        Switch_2                   matlab.ui.control.Switch
        TRONG22Label               matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        Lamp                       matlab.ui.control.Lamp
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a; gripper; wrist; l_arm; u_arm; base; sno=0;
        u_arm_initial; l_arm_initial; base_initial; wrist_initial;
        u_arm_home = 90; l_arm_home = -110; wrist_home = 90; base_home = 0;
        s; Fs;
        d1 = 120; d2 = 0; d3 = 0;
        a1 = 90; a2 = 350; a3 = 260;
        alpha_1 = deg2rad(90); alpha_2 = deg2rad(0); alpha_3 = deg2rad(0);
        time = 0;
    end
    
    
    methods (Access = private)
        
        function [x, y, z, T] = fk(app)
            theta_1 = deg2rad(app.EditField_2.Value);
            theta_2 = deg2rad(app.EditField_3.Value);
            theta_3 = deg2rad(app.EditField_4.Value);
            
            T1 = [cos(theta_1) -sin(theta_1)*cos(app.alpha_1) sin(theta_1)*sin(app.alpha_1) app.a1*cos(theta_1); 
                sin(theta_1) cos(theta_1)*cos(app.alpha_1) -cos(theta_1)*sin(app.alpha_1) app.a1*sin(theta_1);
                0 sin(app.alpha_1) cos(app.alpha_1) app.d1;
                0 0 0 1];

            T2 = [cos(theta_2) -sin(theta_2)*cos(app.alpha_2) sin(theta_2)*sin(app.alpha_2) app.a2*cos(theta_2); 
                sin(theta_2) cos(theta_2)*cos(app.alpha_2) -cos(theta_2)*sin(app.alpha_2) app.a2*sin(theta_2);
                0 sin(app.alpha_2) cos(app.alpha_2) app.d2;
                0 0 0 1];

            T3 = [cos(theta_3) -sin(theta_3)*cos(app.alpha_3) sin(theta_3)*sin(app.alpha_3) app.a3*cos(theta_3); 
                sin(theta_3) cos(theta_3)*cos(app.alpha_3) -cos(theta_3)*sin(app.alpha_3) app.a3*sin(theta_3);
                0 sin(app.alpha_3) cos(app.alpha_3) app.d3;
                0 0 0 1];

            T = T1*T2*T3;
            x = round(T(1, 4), 4);
            y = round(T(2, 4), 4);
            z = round(T(3, 4), 4);
        end
        
        
        function y1 = trajectory(app, n, t, y1)
            syms x;
            
            for i=2:n
                for j=1:n-i+1
                    y1(j,i) = (y1(j+1,i-1) - y1(j,i-1)) / (t(j+i-1) - t(j));
                end
            end

            eq1 = y1(1,1);
            for i=2:n
                eq1 = eq1 + (product(app, x, i, t) * y1(1,i));
            end

            disp(eq1);

            x1 = 1:0.1:t(app.sno);
            y1 = zeros(length(x1));
            for i=1:length(x1)
                y1(i) = subs(eq1, x, x1(i));
            end
        end


        function pro = product(~, val, i, x)
            pro = 1;
            for j=1:i-1
                pro = pro * (val-x(j));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.a = arduino("/dev/cu.usbmodem143301", "Uno", "Libraries","Servo");
            app.a = arduino("COM3", "Uno", "Libraries","Servo");
            app.gripper = servo(app.a, 'D3');
            app.wrist = servo(app.a, 'D5');
            app.l_arm = servo(app.a, 'D6');
            app.u_arm = servo(app.a, 'D9');
            app.base = servo(app.a, 'D10');
               
           writePosition(app.base, 0.5);
           writePosition(app.u_arm, 0.5);
           writePosition(app.l_arm, 1);
           writePosition(app.wrist, 0.5);

           app.EditField_2.Value = round((readPosition(app.base)*180) - 90);            
           app.EditField_3.Value = round(readPosition(app.u_arm)*180);
           app.EditField_4.Value = round(70 - (readPosition(app.l_arm)*180));
           app.EditField_5.Value = round(readPosition(app.wrist)*180);
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.Lamp.Color = 'r';
            app.Button.Enable = "off";
            app.Button_2.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.Button_9.Enable = "off";
            app.Button_10.Enable = "off";
            app.Button_11.Enable = "off";
            app.Button_12.Enable = "off";
            app.Button_13.Enable = "off";
            app.Button_14.Enable = "off";
            app.RecordButton.Enable = "off";
            app.ClearButton.Enable = "off";
            app.PLOTButton.Enable = "off";
            app.Switch.Enable = "off";
            app.PlayButton.Enable = "off";
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            
            if strcmp(value, 'On')
                app.Lamp.Color = 'g';
                app.Button.Enable = "on";
                app.Button_2.Enable = "on";
                app.Button_3.Enable = "on";
                app.Button_4.Enable = "on";
                app.Button_5.Enable = "on";
                app.Button_6.Enable = "on";
                app.Button_7.Enable = "on";
                app.Button_8.Enable = "on";
                app.Button_9.Enable = "on";
                app.Button_10.Enable = "on";
                app.Button_11.Enable = "on";
                app.Button_12.Enable = "on";
                app.Button_13.Enable = "on";
                app.Button_14.Enable = "on";
                app.RecordButton.Enable = "on";
                app.ClearButton.Enable = "on";
                app.PLOTButton.Enable = "on";
                app.Switch.Enable = "on";
                app.PlayButton.Enable = "on";
            else
                app.Lamp.Color = 'r';
                app.Button.Enable = "off";
                app.Button_2.Enable = "off";
                app.Button_3.Enable = "off";
                app.Button_4.Enable = "off";
                app.Button_5.Enable = "off";
                app.Button_6.Enable = "off";
                app.Button_7.Enable = "off";
                app.Button_8.Enable = "off";
                app.Button_9.Enable = "off";
                app.Button_10.Enable = "off";
                app.Button_11.Enable = "off";
                app.Button_12.Enable = "off";
                app.Button_13.Enable = "off";
                app.Button_14.Enable = "off";
                app.RecordButton.Enable = "off";
                app.ClearButton.Enable = "off";
                app.PLOTButton.Enable = "off";
                app.Switch.Enable = "off";
                app.PlayButton.Enable = "off";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.EditField_2.Value;
            if (value<90 && value>=-90)
                app.EditField_2.Value = value + 1;
            end
            value = app.EditField_2.Value;
            app.base_initial = value;

            writePosition(app.base, (value+90)/180);

            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.EditField_2.Value;
            if (value<=90 && value>-90)
                app.EditField_2.Value = value - 1;
            end
            value = app.EditField_2.Value;
            app.base_initial = value;
            
            writePosition(app.base, (value+90)/180);

            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            speed = app.EditField_10.Value;
            speed = (-(5*speed) + 100)/5000;
            
            if app.base_initial > value
                for i=app.base_initial:-1:value
                    writePosition(app.base, (i+90)/180);
                    pause(speed);
                end
            elseif app.base_initial < value
                for i=app.base_initial:1:value
                    writePosition(app.base, (i+90)/180);
                    pause(speed);
                end
            end

            app.base_initial = app.EditField_2.Value;

            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            value = app.EditField_3.Value;
            if (value>=10 && value<90)
                app.EditField_3.Value = value + 1;
            end
            value = app.EditField_3.Value;
            app.u_arm_initial = value;

            writePosition(app.u_arm, value/180);
            
            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;
                
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            value = app.EditField_3.Value;
            if (value>10 && value<=90)
                app.EditField_3.Value = value - 1;
            end
            value = app.EditField_3.Value;
            app.u_arm_initial = value;

            writePosition(app.u_arm, value/180);
            
            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            speed = app.EditField_10.Value;
            speed = (-(5*speed) + 100)/5000;

            if app.u_arm_initial > value
                for i=app.u_arm_initial:-1:value
                    writePosition(app.u_arm, i/180);
                    pause(speed);
                end
            elseif app.u_arm_initial < value
                for i=app.u_arm_initial:1:value
                    writePosition(app.u_arm, i/180);
                    pause(speed);
                end
            end

            app.u_arm_initial = app.EditField_3.Value;

            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            value = app.EditField_4.Value;
            if (value<70 && value>=-110)
                app.EditField_4.Value = value + 1;
            end
            value = app.EditField_4.Value;
            app.l_arm_initial = value;
            
            writePosition(app.l_arm, (70-value)/180);

            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;
                
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            value = app.EditField_4.Value;
            if (value<=70 && value>-110)
                app.EditField_4.Value = value - 1;
            end
            value = app.EditField_4.Value;
            app.l_arm_initial = value;
            
            writePosition(app.l_arm, (70-value)/180);

            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;
                
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            speed = app.EditField_10.Value;
            speed = (-(5*speed) + 100)/5000;

            if app.l_arm_initial > value
                for i=app.l_arm_initial:-1:value
                    writePosition(app.l_arm, (70-i)/180);
                    pause(speed);
                end
            elseif app.l_arm_initial < value
                for i=app.l_arm_initial:1:value
                    writePosition(app.l_arm, (70-i)/180);
                    pause(speed);
                end
            end

            app.l_arm_initial = app.EditField_4.Value;

            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;

        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            value = app.EditField_5.Value;
            if (value>=0 && value<180)
                app.EditField_5.Value = value + 1;
            end
            value = app.EditField_5.Value;
            
            writePosition(app.wrist, value/180);
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            value = app.EditField_5.Value;
            if (value>0 && value<=180)
                app.EditField_5.Value = value - 1;
            end
            value = app.EditField_5.Value;
            
            writePosition(app.wrist, value/180);
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            writePosition(app.wrist, value/180);

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'Open')
                writePosition(app.gripper, 0.3);
            else
                writePosition(app.gripper, 0.85);
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)

            app.sno = app.sno + 1;
            j1 = app.EditField_2.Value;
            j2 = app.EditField_3.Value;
            j3 = app.EditField_4.Value;
            j4 = app.EditField_5.Value;
            x = app.EditField_6.Value;
            y = app.EditField_7.Value;
            z = app.EditField_8.Value;
            g = app.Switch.Value;
            app.UITable.Data = [app.UITable.Data; {j1, j2, j3, j4, x, y, z, g}];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data = {};
            app.sno = 0;
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            rows = app.sno;

               
%% Work in this function  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%5


            if strcmp(app.DropDown.Value, 'Joint 1')
                t = 1:rows;
               % t = [0 8 16 24];
                y = zeros(rows, rows);
                data = app.UITable.Data;
                for i=1:rows
                    y(i,1) = str2double(string(data(i,1)));
                end
                y1 = trajectory(app, rows, t, y);
                plot(app.UIAxes, y1);
                xlabel(app.UIAxes, "time(t)");
                ylabel(app.UIAxes, "Joint angle(θ)");

            elseif strcmp(app.DropDown.Value, 'Joint 2')
                t = 1:rows;
               % t = [0 8 16 24];
                y = zeros(rows, rows);
                data = app.UITable.Data;
                for i=1:rows
                    y(i,1) = str2double(string(data(i,2)));
                end
                y1 = trajectory(app, rows, t, y);
                plot(app.UIAxes, y1);

            elseif strcmp(app.DropDown.Value, 'Joint 3')
                t = 1:rows;
               % t = [0 8 16 24];
                y = zeros(rows, rows);
                data = app.UITable.Data;
                for i=1:rows
                    y(i,1) = str2double(string(data(i,3)));
                end
                y1 = trajectory(app, rows, t, y);
                plot(app.UIAxes, y1);
                
            elseif strcmp(app.DropDown.Value, 'Joint 4')
                t = 1:rows;
               % t = [0 8 16 24];
                y = zeros(rows, rows);
                data = app.UITable.Data;
                for i=1:rows
                    y(i,1) = str2double(string(data(i,4)));
                end
                y1 = trajectory(app, rows, t, y);
                plot(app.UIAxes, y1);
                

            elseif strcmp(app.DropDown.Value, 'End Effector')
                t = 1:rows;
               % t = [0 8 16 24];
                x = zeros(rows, rows);
                y = zeros(rows, rows);
                z = zeros(rows, rows);
                data = app.UITable.Data;
                for i=1:rows
                    x(i,1) = str2double(string(data(i,5)));
                    y(i,1) = str2double(string(data(i,6)));
                    z(i,1) = str2double(string(data(i,7)));
                end
                X = trajectory(app, rows, t, x);
                Y= trajectory(app, rows, t, y);
                Z = trajectory(app, rows, t, z);
                plot(app.UIAxes, [X, Y, Z]);
                legend(app.UIAxes, 'X', 'Y', 'Z');

            end
        end

        % Value changed function: LockCheckBox
        function LockCheckBoxValueChanged(app, event)
            value = app.LockCheckBox.Value;
            if value==true
                app.EditField_10.Enable = false;
            else
                app.EditField_10.Enable = true;
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            speed = app.EditField_10.Value;
            speed = (-(5*speed) + 100)/5000;
            
            j1_value = app.EditField_2.Value;
            j2_value = app.EditField_3.Value;
            j3_value = app.EditField_4.Value;
            j4_value = app.EditField_5.Value;
            
            %% Homing base
            if j1_value > app.base_home
                for i=j1_value:-1:app.base_home
                    writePosition(app.base, (i+90)/180);
                    pause(speed);
                end
            elseif j1_value < app.base_home
                for i=j1_value:1:app.base_home
                    writePosition(app.base, (i+90)/180);
                    pause(speed);
                end
            end

            %% Homing upper arm
            if j2_value > app.u_arm_home
                for i=j2_value:-1:app.u_arm_home
                    writePosition(app.u_arm, i/180);
                    pause(speed);
                end
            elseif j2_value < app.u_arm_home
                for i=j2_value:1:app.u_arm_home
                    writePosition(app.u_arm, i/180);
                    pause(speed);
                end
            end

            %% Homing Lower arm
            if j3_value > app.l_arm_home
                for i=j3_value:-1:app.l_arm_home
                    writePosition(app.l_arm, (70-i)/180);
                    pause(speed);
                end
            elseif j3_value < app.l_arm_home
                for i=j3_value:1:app.l_arm_home
                    writePosition(app.l_arm, (70-i)/180);
                    pause(speed);
                end
            end

            %% Homing Wrist
            if j4_value > app.wrist_home
                for i=j4_value:-1:app.wrist_home
                    writePosition(app.wrist, i/180);
                    pause(speed);
                end
            elseif j4_value < app.wrist_home
                for i=j4_value:1:app.wrist_home
                    writePosition(app.wrist, i/180);
                    pause(speed);
                end
            end
               
            %% Changing Editfield values
            app.EditField_2.Value = app.base_home;
            app.EditField_3.Value = app.u_arm_home;
            app.EditField_4.Value = app.l_arm_home;
            app.EditField_5.Value = app.wrist_home;

            %% Forward Kinematics Calculation
            [x, y, z] = fk(app);
            app.EditField_6.Value = x;
            app.EditField_7.Value = y;
            app.EditField_8.Value = z;

        end

        % Value changed function: OUTPUTListBox
        function OUTPUTListBoxValueChanged(app, event)
            value = app.OUTPUTListBox.Value;
            if strcmp(value, 'Table')
                app.UITable.Visible = "on";
                app.UIAxes.Visible = "off";
                app.UITable2.Visible = "off";
                app.UITable3.Visible = "off";
                app.DHParametersLabel.Visible = "off";
                app.TransformationMatrixLabel.Visible = "off";
            elseif strcmp(value, 'Graph')
                app.UITable.Visible = "off";
                app.UIAxes.Visible = "on";
                app.UITable2.Visible = "off";
                app.UITable3.Visible = "off";
                app.DHParametersLabel.Visible = "off";
                app.TransformationMatrixLabel.Visible = "off";
            elseif strcmp(value, 'DH Parameters')
                app.UITable.Visible = "off";
                app.UIAxes.Visible = "off";
                app.UITable2.Visible = "on";
                app.UITable3.Visible = "off";
                app.DHParametersLabel.Visible = "on";
                app.TransformationMatrixLabel.Visible = "off";

                app.UITable2.Data = [{app.d1, app.EditField_2.Value, app.a1, app.alpha_1}; {app.d2, app.EditField_3.Value, app.a2, app.alpha_2}; {app.d3, app.EditField_4.Value, app.a3, app.alpha_3}];
            else
                app.UITable.Visible = "off";
                app.UIAxes.Visible = "off";
                app.UITable2.Visible = "off";
                app.UITable3.Visible = "on";
                app.DHParametersLabel.Visible = "off";
                app.TransformationMatrixLabel.Visible = "on";
                   
                [x, y, z, T] = fk(app);
                app.UITable3.Data = [{T(1,1), T(1,2), T(1,3), T(1,4)}; {T(2,1), T(2,2), T(2,3), T(2,4)}; {T(3,1), T(3,2), T(3,3), T(3,4)}; {T(4,1), T(4,2), T(4,3), T(4,4)}];
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonValueChanged(app, event)
            data = app.UITable.Data;
            speed = app.EditField_10.Value;
            speed = (-(5*speed) + 100)/5000;
            app.wrist_initial = app.EditField_5.Value;
            for k=1:app.LoopEditField.Value
                for j=1:app.sno
                    j1 = str2double(string(data(j,1)));
                    j2 = str2double(string(data(j,2)));
                    j3 = str2double(string(data(j,3)));
                    j4 = str2double(string(data(j,4)));
                    
                    % Specified gripper state
                    value = data(j, 8);
                    if strcmp(value, 'Open')
                        writePosition(app.gripper, 0.3);
                    else
                        writePosition(app.gripper, 0.85);
                    end

                    % going to specified joint 1
                    if app.base_initial > j1
                        for i=app.base_initial:-1:j1
                            writePosition(app.base, (i+90)/180);
                            pause(speed);
                        end
                    elseif app.base_initial < j1
                        for i=app.base_initial:1:j1
                           writePosition(app.base, (i+90)/180);
                           pause(speed);
                        end
                    end

                    % going to specified joint 2
                    if app.u_arm_initial > j2
                        for i=app.u_arm_initial:-1:j2
                            writePosition(app.u_arm, i/180);
                            pause(speed);
                        end
                    elseif app.u_arm_initial < j2
                        for i=app.u_arm_initial:1:j2
                            writePosition(app.u_arm, i/180);
                            pause(speed);
                        end
                    end

                    % going to specified joint 3
                    if app.l_arm_initial > j3
                        for i=app.l_arm_initial:-1:j3
                            writePosition(app.l_arm, (70-i)/180);
                            pause(speed);
                        end
                    elseif app.l_arm_initial < j3
                        for i=app.l_arm_initial:1:j3
                            writePosition(app.l_arm, (70-i)/180);
                            pause(speed);
                        end
                    end

                    % going to specified joint 4
                    writePosition(app.wrist, j4/180);


                    app.base_initial = j1;
                    app.u_arm_initial = j2;
                    app.l_arm_initial = j3;
                    app.wrist_initial = j4;

                end
            end
      %     app.EditField_2.Value = round((readPosition(app.base)*180) - 90);            
       %    app.EditField_3.Value = round(readPosition(app.u_arm)*180);
        %   app.EditField_4.Value = round(70 - (readPosition(app.l_arm)*180));
         %  app.EditField_5.Value = round(readPosition(app.wrist)*180);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Lamp = uilamp(app.Panel);
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Value = 'TRONWARE';
            app.TRONG22Label = uilabel(app.Panel);
            app.TRONG22Label.Text = 'TRON G-22';
            app.Switch_2 = uiswitch(app.Panel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.SpeedLabel = uilabel(app.Panel);
            app.SpeedLabel.Text = 'Speed';
            app.EditField_10 = uieditfield(app.Panel, 'numeric');
            app.EditField_10.Limits = [1 10];
            app.EditField_10.RoundFractionalValues = 'on';
            app.EditField_10.Enable = 'off';
            app.EditField_10.Value = 5;
            app.LockCheckBox = uicheckbox(app.Panel);
            app.LockCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCheckBoxValueChanged, true);
            app.LockCheckBox.Text = 'Lock';
            app.LockCheckBox.Value = true;
            app.Button_15 = uibutton(app.Panel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Icon = fullfile(pathToMLAPP, 'toppng.com-blue-home-page-icon-png-icon-png-button-home-894x893.png');
            app.Button_15.Text = '';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.LoopEditFieldLabel = uilabel(app.Panel);
            app.LoopEditFieldLabel.Text = 'Loop';
            app.LoopEditField = uieditfield(app.Panel, 'numeric');
            app.LoopEditField.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.J1Label = uilabel(app.Panel_2);
            app.J1Label.Text = 'J1';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.J2Label = uilabel(app.Panel_2);
            app.J2Label.Text = 'J2';
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.J3Label = uilabel(app.Panel_2);
            app.J3Label.Text = 'J3';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '-';
            app.Button_7 = uibutton(app.Panel_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '+';
            app.J4Label = uilabel(app.Panel_2);
            app.J4Label.Text = 'J4';
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '-';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_2.Limits = [-90 90];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_3.Limits = [10 90];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 90;
            app.EditField_4 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_4.Limits = [-110 70];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 70;
            app.EditField_5 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_5.Limits = [0 180];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 90;
            app.GripperLabel = uilabel(app.Panel_2);
            app.GripperLabel.Text = 'Gripper';
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'Close', 'Open'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Close';
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.Text = '+';
            app.XLabel = uilabel(app.Panel_2);
            app.XLabel.Text = 'X';
            app.Button_10 = uibutton(app.Panel_2, 'push');
            app.Button_10.Text = '-';
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.Text = '+';
            app.YLabel = uilabel(app.Panel_2);
            app.YLabel.Text = 'Y';
            app.Button_12 = uibutton(app.Panel_2, 'push');
            app.Button_12.Text = '-';
            app.Button_13 = uibutton(app.Panel_2, 'push');
            app.Button_13.Text = '+';
            app.ZLabel = uilabel(app.Panel_2);
            app.ZLabel.Text = 'Z';
            app.Button_14 = uibutton(app.Panel_2, 'push');
            app.Button_14.Text = '-';
            app.EditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.Panel_2);
            app.PlotTrajectoryLabel = uilabel(app.Panel_3);
            app.PlotTrajectoryLabel.Text = 'Plot Trajectory';
            app.DropDown = uidropdown(app.Panel_3);
            app.DropDown.Items = {'Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'End Effector'};
            app.DropDown.Value = 'Joint 1';
            app.PLOTButton = uibutton(app.Panel_3, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.OUTPUTListBoxLabel = uilabel(app.Panel_3);
            app.OUTPUTListBoxLabel.Text = 'OUTPUT';
            app.OUTPUTListBox = uilistbox(app.Panel_3);
            app.OUTPUTListBox.Items = {'Table', 'Graph', 'DH Parameters', 'Transformation Matrix'};
            app.OUTPUTListBox.ValueChangedFcn = createCallbackFcn(app, @OUTPUTListBoxValueChanged, true);
            app.OUTPUTListBox.Value = 'Table';
            app.RecordButton = uibutton(app.Panel_2, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.ClearButton = uibutton(app.Panel_2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'J1'; 'J2'; 'J3'; 'J4'; 'X'; 'Y'; 'Z'; 'Gripper'};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'d'; 'θ'; 'a'; 'α'};
            app.UITable2.Visible = 'off';
            app.DHParametersLabel = uilabel(app.UIFigure);
            app.DHParametersLabel.Visible = 'off';
            app.DHParametersLabel.Text = 'DH Parameters';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.UITable3.Visible = 'off';
            app.TransformationMatrixLabel = uilabel(app.UIFigure);
            app.TransformationMatrixLabel.Visible = 'off';
            app.TransformationMatrixLabel.Text = 'Transformation Matrix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRONWARE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end